classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MNISTTestUIFigure  matlab.ui.Figure
        TestingPanel       matlab.ui.container.Panel
        OpenDrawBoxButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
        TestButton         matlab.ui.control.Button
        LoadNetworkButton  matlab.ui.control.Button
        AutoCheckBox       matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        im;
        im_data;
        net;
        t=[0,1,2,3,4,5,6,7,8,9];
        y;
        value;
    end
    
    methods (Access = public)
    function classifyimg(app)
            app.y=app.net(app.im_data);
            bar(app.y);
            %title(label)
        end        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: OpenDrawBoxButton
        function OpenDrawBoxButtonPushed(app, event)
            app.im=paint;
            imshow(app.im,'Parent',app.UIAxes);
            if app.value
                app.TestButtonPushed;
            end
        end

        % Button pushed function: LoadNetworkButton
        function LoadNetworkButtonPushed(app, event)
            app.net=loadNet;
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            [conf,result]=classifyMNIST(app.net,app.im);
            app.UIAxes.Title.String=append('Result: ',num2str(result),' [',num2str(conf*100),'%]');
        end

        % Callback function
        function PlotConfussionMatButtonPushed(app, event)
            figure
            plotconfusion(app.t,app.y');
        end

        % Value changed function: AutoCheckBox
        function AutoCheckBoxValueChanged(app, event)
            app.value = app.AutoCheckBox.Value;
            if app.value
                app.TestButton.Enable=false;
            else
                app.TestButton.Enable=true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MNISTTestUIFigure = uifigure('Visible', 'off');
            app.MNISTTestUIFigure.Name = 'MNIST Test';
            app.TestingPanel = uipanel(app.MNISTTestUIFigure);
            app.TestingPanel.Title = 'Testing';
            app.OpenDrawBoxButton = uibutton(app.TestingPanel, 'push');
            app.OpenDrawBoxButton.ButtonPushedFcn = createCallbackFcn(app, @OpenDrawBoxButtonPushed, true);
            app.OpenDrawBoxButton.Text = 'Open Draw Box';
            app.UIAxes = uiaxes(app.TestingPanel);
            title(app.UIAxes, 'Draw Result')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.TestButton = uibutton(app.TestingPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.LoadNetworkButton = uibutton(app.TestingPanel, 'push');
            app.LoadNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNetworkButtonPushed, true);
            app.LoadNetworkButton.Text = 'Load Network';
            app.AutoCheckBox = uicheckbox(app.TestingPanel);
            app.AutoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutoCheckBoxValueChanged, true);
            app.AutoCheckBox.Text = 'Auto';
            % Show the figure after all components are created
            app.MNISTTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MNISTTestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MNISTTestUIFigure)
        end
    end
end